classdef Image_Browser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ImageViewerPanel         matlab.ui.container.Panel
        FrametypeEditField       matlab.ui.control.EditField
        FrametypeEditFieldLabel  matlab.ui.control.Label
        ImageOptions             matlab.ui.container.Panel
        MaxEditField             matlab.ui.control.NumericEditField
        MaxEditFieldLabel        matlab.ui.control.Label
        MinEditFieldLabel        matlab.ui.control.Label
        MinEditField             matlab.ui.control.NumericEditField
        FrameType                matlab.ui.control.ListBox
        FileSelected             matlab.ui.control.EditField
        SelectedimageLabel       matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        BrowserPanel             matlab.ui.container.Panel
        UITable                  matlab.ui.control.Table
        ImageFolder              matlab.ui.control.EditField
        RefreshButton            matlab.ui.control.Button
        FolderButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            path = uigetdir;
            app.ImageFolder.Value = path;

            % list all files with .fits extension
            file_list = dir(fullfile(app.ImageFolder.Value, '*.fits'));
            file_names = {file_list.name};
            file_names = file_names';

            set(app.UITable,'Data',file_names);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % list all files with .fits extension
            file_list = dir(fullfile(app.ImageFolder.Value, '*.fits'));
            file_names = {file_list.name};
            file_names = file_names';

            set(app.UITable,'Data',file_names);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            imageList = get(app.UITable,'Data');

            % set Selected image value:
            app.FileSelected.Value = imageList{indices};

            %%%%%%%%%%%%%%%%%%%%%%%%%
            % read the fits file
            img = fitsread(fullfile(app.ImageFolder.Value,app.FileSelected.Value), 'primary');
            frame_type = app.FrameType.Value;


            if frame_type == "OD"
                frame = real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));

                min = app.MinEditField.Value;
                max = app.MaxEditField.Value;
                image(frame,'Parent',app.UIAxes,'CDataMapping','scaled');
                colormap(app.UIAxes,gray(32768));
                app.UIAxes.CLim = [min, max];

            else
                if frame_type == "FakeOD"
                    frame=real(((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));

                    min = app.MinEditField.Value;
                    max = app.MaxEditField.Value;
                    image(frame,'Parent',app.UIAxes,'CDataMapping','scaled');
                    colormap(app.UIAxes,gray(32768));
                    app.UIAxes.CLim = [min, max];

                else
                    if (frame_type == "With atoms")
                        frame=img(:,:,1);
                        image(frame,'Parent',app.UIAxes,'CDataMapping','direct');
                        colormap(app.UIAxes,gray(32768));
                    elseif (frame_type == "Without atoms")
                        frame=img(:,:,2);
                        image(frame,'Parent',app.UIAxes,'CDataMapping','direct');
                        colormap(app.UIAxes,gray(32768));
                    elseif (frame_type == "Dark")
                        frame=img(:,:,3);
                        image(frame,'Parent',app.UIAxes,'CDataMapping','direct');
                        colormap(app.UIAxes,gray(32768));
                    else
                        frame=real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
                        min = app.MinEditField.Value;
                        max = app.MaxEditField.Value;
                        image(frame,'Parent',app.UIAxes,'CDataMapping','scaled');
                        colormap(app.UIAxes,gray(32768));
                        app.UIAxes.CLim = [min, max];
                    end
                end
                
            end
            set(app.UIAxes,'DataAspectRatioMode','manual','DataAspectRatio',[1 1 1],'YDir','normal') ;
            %%%%%%%%%%%%%%%%%%%%%%%%%


            % show image (EXAMPLE)
            % app.Image.ImageSource = fullfile(app.ImageFolder.Value,app.FileSelected.Value);


        end

        % Value changed function: FrameType
        function FrameTypeValueChanged(app, event)
            value = app.FrameType.Value;

            app.FrametypeEditField.Value = value;

            %%%%%%%%%%%%%%%%%%%%%%%%
            % read the fits file
            img = fitsread(fullfile(app.ImageFolder.Value,app.FileSelected.Value), 'primary');
            frame_type = app.FrameType.Value;


            if frame_type == "OD"
                frame = real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));

                min = app.MinEditField.Value;
                max = app.MaxEditField.Value;
                image(frame,'Parent',app.UIAxes,'CDataMapping','scaled');
                colormap(app.UIAxes,gray(32768));
                app.UIAxes.CLim = [min, max];


            else
                if frame_type == "FakeOD"
                    frame=real(((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));

                    min = app.MinEditField.Value;
                    max = app.MaxEditField.Value;
                    image(frame,'Parent',app.UIAxes,'CDataMapping','scaled');
                    colormap(app.UIAxes,gray(32768));
                    app.UIAxes.CLim = [min, max];

                else
                    if (frame_type == "With atoms")
                        frame=img(:,:,1);
                        image(frame,'Parent',app.UIAxes,'CDataMapping','direct');
                        colormap(app.UIAxes,gray(32768));
                    elseif (frame_type == "Without atoms")
                        frame=img(:,:,2);
                        image(frame,'Parent',app.UIAxes,'CDataMapping','direct');
                        colormap(app.UIAxes,gray(32768));
                    elseif (frame_type == "Dark")
                        frame=img(:,:,3);
                        image(frame,'Parent',app.UIAxes,'CDataMapping','direct');
                        colormap(app.UIAxes,gray(32768));
                    else
                        frame=real(-log((img(:,:,1)-img(:,:,3))./(img(:,:,2)-img(:,:,3))));
                        min = app.MinEditField.Value;
                        max = app.MaxEditField.Value;
                        image(frame,'Parent',app.UIAxes,'CDataMapping','scaled');
                        colormap(app.UIAxes,gray(32768));
                        app.UIAxes.CLim = [min, max];
                    end
                end
            end
            set(app.UIAxes,'DataAspectRatioMode','manual','DataAspectRatio',[1 1 1],'YDir','normal') ;
            %%%%%%%%%%%%%%%%%%%%%%%%

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowserPanel = uipanel(app.UIFigure);
            app.BrowserPanel.Title = 'Browser';
            app.FolderButton = uibutton(app.BrowserPanel, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Tag = 'folder_name';
            app.FolderButton.Text = 'Folder';
            app.RefreshButton = uibutton(app.BrowserPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ImageFolder = uieditfield(app.BrowserPanel, 'text');
            app.ImageFolder.Tag = 'path_name';
            app.UITable = uitable(app.BrowserPanel);
            app.UITable.ColumnName = {'FITS images:'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ImageViewerPanel = uipanel(app.UIFigure);
            app.ImageViewerPanel.Title = 'Image Viewer';
            app.UIAxes = uiaxes(app.ImageViewerPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SelectedimageLabel = uilabel(app.ImageViewerPanel);
            app.SelectedimageLabel.Text = 'Selected image: ';
            app.FileSelected = uieditfield(app.ImageViewerPanel, 'text');
            app.ImageOptions = uipanel(app.ImageViewerPanel);
            app.FrameType = uilistbox(app.ImageOptions);
            app.FrameType.Items = {'OD', 'With atoms', 'Without atoms', 'Dark', 'FakeOD'};
            app.FrameType.ValueChangedFcn = createCallbackFcn(app, @FrameTypeValueChanged, true);
            app.FrameType.Value = 'OD';
            app.MinEditField = uieditfield(app.ImageOptions, 'numeric');
            app.MinEditFieldLabel = uilabel(app.ImageOptions);
            app.MinEditFieldLabel.Text = 'Min';
            app.MaxEditFieldLabel = uilabel(app.ImageOptions);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.ImageOptions, 'numeric');
            app.MaxEditField.Value = 1.3;
            app.FrametypeEditFieldLabel = uilabel(app.ImageViewerPanel);
            app.FrametypeEditFieldLabel.Text = 'Frame type:';
            app.FrametypeEditField = uieditfield(app.ImageViewerPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Browser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end